classdef RP_data_tool_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout2                   matlab.ui.container.GridLayout
        TabGroup2                     matlab.ui.container.TabGroup
        bersichtTab                   matlab.ui.container.Tab
        Uebersicht_HTML               matlab.ui.control.HTML
        FahrdynamikTab                matlab.ui.container.Tab
        Fahrdynamik_HTML              matlab.ui.control.HTML
        FahrerTab                     matlab.ui.container.Tab
        Fahrer_HTML                   matlab.ui.control.HTML
        FahrerprofileTab              matlab.ui.container.Tab
        Fahrerprofile_HTML            matlab.ui.control.HTML
        HardwareSoftwareTab           matlab.ui.container.Tab
        Hardware_Software_HTML        matlab.ui.control.HTML
        AkkuTab                       matlab.ui.container.Tab
        Akku_HTML                     matlab.ui.control.HTML
        MechanikTab                   matlab.ui.container.Tab
        Mechanik_HTML                 matlab.ui.control.HTML
        FahrzeugParameterTab          matlab.ui.container.Tab
        FahrzeugParameter_HTML        matlab.ui.control.HTML
        ScratchbookTab                matlab.ui.container.Tab
        Scratchbook_HTML              matlab.ui.control.HTML
        AkkuGesundheitTab             matlab.ui.container.Tab
        AkkuGesundheit_HTML           matlab.ui.control.HTML
        thermikTab                    matlab.ui.container.Tab
        Thermik_HTML                  matlab.ui.control.HTML
        Potis                         matlab.ui.container.Tab
        Potis_HTML                    matlab.ui.control.HTML
        AuswahlPanel                  matlab.ui.container.Panel
        GridLayout                    matlab.ui.container.GridLayout
        dd_datei                      matlab.ui.control.DropDown
        DropDown_3                    matlab.ui.control.DropDown
        xAchseDropDown                matlab.ui.control.DropDown
        xAchseDropDownLabel           matlab.ui.control.Label
        LadenButton                   matlab.ui.control.Button
        OrdnerwhlenButton             matlab.ui.control.Button
        Panel                         matlab.ui.container.Panel
        ReifentestViewerCarNo62Label  matlab.ui.control.Label
        DynamicseVLabel               matlab.ui.control.Label
    end

    %% Property Definitionene

    % Propertys für Backend
    properties (Access = private)
        fig                          % uifigure
        tabgroup                     % uitabgroup
        HTML_Handles                 % Gesammelte Handles
       
        store        = struct([])    % Signal-Store (load_mf4)
        x            = struct([])    % aktuelle x-Achse (berechne_x_achse)
        params                       % Fahrzeugparameter
        runden       = struct('name', {}, 't_start', {}, 't_ende', {}, 'fahrer', {})
        ordner       = ''            % aktuell gewaehlter Datenordner
        x_modus      = 'distanz'     % Standard laut Konzept
        payload_map                  % containers.Map: tabname -> function_handle

        dd_runde                     % Dropdown: Runde
        lbl_ordner                   % Label: gewaehlter Ordner
        html_dir                     % Ordner mit den Tab-HTML-Dateien (+ ./lib)

        % Reihenfolge = Tab-Reihenfolge in der Oberflaeche
        tab_namen = { 'uebersicht','fahrdynamik','fahrer','fahrerprofile', ...
            'hardware_software','akku','mechanik','parameter','scratchbook',  'akku_gesundheit', 'thermik', 'potis'}
        tab_titel = { 'Übersicht','Fahrdynamik','Fahrer','Fahrerprofile', ...
            'Hardware/Software','Akku','Mechanik','Fahrzeug-Parameter','Scratchbook', 'akku_gesundheit', 'thermik', 'Potis'}

        % Team-Branding (aus dem Reifen-Tool uebernommen)
        C_ROT    = [0.7804 0.1333 0.1608]
        C_BG     = [0.1200 0.1200 0.1200]
        C_FG     = [0.9000 0.9000 0.8800]
        FONT_MARKE = 'Rift'

    end

    % Properties for Java Scripts
    properties (Access = public)
         data_analysis_startup_HTML                   matlab.ui.control.HTML
         
    end

    % Alle Eigenen functions
    methods (Access = private)

        % Zentrale Plot functions
        function alle_tabs_aktualisieren(app)

            % Weitergabe aller Payloads über einen Loop
            for i = 1:numel(app.tab_namen)
                current_tab_name = app.tab_namen{i};
                if isempty(fieldnames(app.store))
                    return;
                end
                fh = app.payload_map(current_tab_name);         % eigene Function je Tab
                pl = fh(app.store, app.x, app.params, app.runden);

                h  = app.HTML_Handles(i);   % erst Handle holen (containers.Map),
                h.Data = pl;               % dann Dateivertrag setzen
                
            end
        end

        function tab_aktualisieren(app, name)
            % Fahrerprofil-Tab arbeitet auf der Profildatei und laeuft auch
            % ohne geladenen Run; alle anderen brauchen den Store.
            if isempty(fieldnames(app.store)) && ~strcmp(name, 'fahrerprofile')
                return;
            end
            pl = app.baue_payload(name);
            h  = app.HTML_Handles(name);   % erst Handle holen (containers.Map),
            h.Data = pl;               % dann Dateivertrag setzen
        end

        function pl = baue_payload(app, name)
            fh = app.payload_map(name);         % eigene Function je Tab
            pl = fh(app.store, app.x, app.params, app.runden);
        end
        % Payload functions

        % Payload Helper
        function initalisiere_payloads_and_handels(app)
            app.payload_map = containers.Map( app.tab_namen, { ...
                @payload_uebersicht, @payload_fahrdynamik, @payload_fahrer, ...
                @payload_fahrerprofile, @payload_hardware_software, @payload_akku, ...
                @payload_mechanik, @payload_parameter, @payload_scratchbook, ...
                @payload_akku_gesundheit, @payload_thermik, @payload_potis} );

            app.HTML_Handles = [app.Uebersicht_HTML; app.Fahrdynamik_HTML; app.Fahrer_HTML; app.Fahrerprofile_HTML; app.Hardware_Software_HTML; app.Akku_HTML; app.Mechanik_HTML; app.FahrzeugParameter_HTML; app.Scratchbook_HTML; app.AkkuGesundheit_HTML; app.Thermik_HTML; app.Potis_HTML];
        end

        % Helper Ladebalken
        function setze_fortschritt(~, dlg, anteil, text)
            if isvalid(dlg)
                dlg.Value   = max(0, min(1, anteil));
                dlg.Message = text;
            end
        end

        % Eigene Callbacks
        function StartupHTMLDataChanged(app, event)
            if isequal(event.Data, "start")
                delete(app.data_analysis_startup_HTML);   % Startup-Screen entfernen
            end
        end

        function fortschritt_senden(app, prozent, text)
            try
                sendEventToHTML(app.AkkuGesundheit_HTML, 'fortschritt', ...
                    struct('prozent', prozent, 'text', text));
                drawnow limitrate   % Event zum Browser durchreichen (waehrend langer Schleife)
            catch
                % HTML evtl. noch nicht bereit – ignorieren
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Startup der App
            clc

            % Hinzufügen der Dateipfade
            addpath("HTMLS", "Functions", "configs")

            % % Erstellen der Startmaske
            app.data_analysis_startup_HTML = uihtml(app.UIFigure);
            app.data_analysis_startup_HTML.Position = [1 1 app.UIFigure.Position(3) app.UIFigure.Position(4)];
            app.data_analysis_startup_HTML.HTMLSource = fullfile(pwd, 'HTMLS\data_analysis_startup.html');
            app.data_analysis_startup_HTML.DataChangedFcn = createCallbackFcn(app, @StartupHTMLDataChanged, true);

            % Laden von Config datein
            % app.params.signalconfig_datei = fullfile(app.html_dir, 'signalconfigs.mat');
            app.params.signalconfig_datei = 'signalconfigs.mat';
            app.params = fahrzeug_parameter();
            app.params.scratchconfig_datei = fullfile(app.html_dir, 'scratchconfigs.mat');
            app.params.scratch_stamp = 0;
            % app.params.profil_datei = fullfile(app.html_dir, 'fahrerprofile.mat');  % Veruracht Problemem beim Laden in der HTML Seblst

            % intislisieren der payloads und handels
            initalisiere_payloads_and_handels(app)

            % Optionale Akku-Geometrie in den Parametern (sonst Default 12x12 im Scan):
            if ~isfield(app.params,'akku') || ~isstruct(app.params.akku), app.params.akku = struct(); end
            app.params.akku.stacks            = 12;    % Anzahl Stacks
            app.params.akku.zellen_pro_stack  = 12;    % Zellen je Stack
            % app.params.akku.uv_warn_v       = 3.2;   % V, optionale Schwelle
            % app.params.akku.i_min_fit_a     = 5;     % A, optionale Mindest-Stromspanne fuer R-Fit

            % Tab beim Start einmal (leer) rendern, damit der Starthinweis erscheint:
            h0 = app.payload_map('akku_gesundheit');
            app.AkkuGesundheit_HTML.Data = h0(app.store, app.x, app.params, app.runden);
            % (app.store/app.x/app.runden duerfen hier leer/undefiniert sein – der
            %  Payload ignoriert sie und liefert den Leer-Zustand.)


            if ~isfield(app.params,'profil_datei') || isempty(app.params.profil_datei)
                app.params.profil_datei = fullfile(pwd, 'Functions', 'fahrerprofile.mat');
            end


        end

        % Button pushed function: LadenButton
        function LadenButtonPushed(app, event)
            pfad = app.dd_datei.Value;
            if isempty(pfad)
                uialert(app.fig, 'Bitte erst einen Ordner wählen und eine Datei auswählen.', ...
                    'Keine Datei');
                return;
            end

            dlg = uiprogressdlg(app.UIFigure, 'Title', 'Lade Logdatei', ...
                'Indeterminate', 'off', 'Cancelable', 'off');
            cb  = @(anteil, text) app.setze_fortschritt(dlg, anteil, text);
            try
                app.store = load_mf4(pfad, cb);
            catch err
                close(dlg);
                uialert(app.UIFigure, err.message, 'Fehler beim Laden');
                return;
            end
            close(dlg);

            app.x = berechne_x_achse(app.store, app.x_modus, app.params);
            app.alle_tabs_aktualisieren();
        end

        % Button pushed function: OrdnerwhlenButton
        function OrdnerwhlenButtonPushed(app, event)

            % Auswhälen des Ordners mit den Logging daten
            start = 'C:\Users\Danie\OneDrive\Desktop\RP26_Data_Analysis\Logging_data';
            directory = uigetdir(start, 'Ordner mit Testdaten wählen');
            % directory = 'C:\Users\Danie\OneDrive\Desktop\RP26_Data_Analysis\Logging_data\RP26_First_Testday_12_07_26';

            % Befüllen des Dropdowns mit den Im Ordner vorhandenen MF4 Datein
            dateien = dir(fullfile(directory, '*.mf4'));
            if isempty(dateien)
                app.dd_datei.Items     = {'— keine .mf4 im Ordner —'};
                app.dd_datei.ItemsData = {''};
                return;
            end
            namen = {dateien.name};
            app.dd_datei.Items     = namen;
            app.dd_datei.ItemsData = fullfile(directory, namen);
            app.dd_datei.Value     = app.dd_datei.ItemsData{1};

        end

        % Event received function: Fahrerprofile_HTML
        function Fahrerprofile_HTMLEventReceived(app, event)

            switch event.HTMLEventName
                case 'fahrer_anlegen'
                    d = event.HTMLEventData;
                    if isfield(d, 'name') && isfield(d, 'kennzahlen')
                        try
                            fahrerprofile_speichern(app.params.profil_datei, d.name, d.kennzahlen);
                        catch err
                            uialert(app.UIFigure, err.message, 'Fahrerprofile');
                        end
                    end

                case 'profil_datei_waehlen'
                    start = app.params.profil_datei;
                    if isempty(start), start = fullfile(pwd, 'fahrerprofile.mat'); end
                    [f, p] = uiputfile({'*.mat','Fahrerprofil-Datei'}, 'Profildatei waehlen oder anlegen', start);
                    figure(app.UIFigure);            % Fokus zurueck auf die App
                    if ~isequal(f, 0)
                        app.params.profil_datei = fullfile(p, f);
                    end

                otherwise
                    warning('DatenanalyseApp:Event', 'Unbehandeltes Event "%s".', event.HTMLEventName);
                    return;
            end

            % Tab neu versorgen (Index 4 = Fahrerprofile)
            fh = app.payload_map(app.tab_namen{4});
            app.HTML_Handles(4).Data = fh(app.store, app.x, app.params, app.runden);

        end

        % Event received function: Hardware_Software_HTML
        function Hardware_Software_HTMLEventReceived(app, event)

            switch event.HTMLEventName
                case 'signalconfig_speichern'
                    d = event.HTMLEventData;
                    signalconfigs_speichern(app.params.signalconfig_datei, d.name, d.signale);
                case 'signalconfig_loeschen'
                    signalconfigs_loeschen(app.params.signalconfig_datei, event.HTMLEventData.name);
            end
            % Tab neu versorgen, damit die Config-Liste aktuell ist
            idx = 5;   % Index des Hardware/Software-Tabs in tab_namen
            fh = app.payload_map(app.tab_namen{idx});
            app.HTML_Handles(idx).Data = fh(app.store, app.x, app.params, app.runden);

        end

        % Event received function: Scratchbook_HTML
        function Scratchbook_HTMLEventReceived(app, event)

            d = event.HTMLEventData;
            switch event.HTMLEventName
                case 'scratch_render'
                    app.params.scratch_config = d.config;
                case 'scratch_config_laden'
                    cfgs = scratchconfigs_laden(app.params.scratchconfig_datei);
                    idx = find(strcmp({cfgs.name}, d.name), 1);
                    if ~isempty(idx)
                        app.params.scratch_config = cfgs(idx).config;
                        app.params.scratch_stamp  = app.params.scratch_stamp + 1;  % Builder neu füllen
                    end
                case 'scratch_config_speichern'
                    scratchconfigs_speichern(app.params.scratchconfig_datei, d.name, d.config);
                case 'scratch_config_loeschen'
                    scratchconfigs_loeschen(app.params.scratchconfig_datei, d.name);
            end
            fh = app.payload_map('scratchbook');
            app.HTML_Handles(strcmp(app.tab_namen,'scratchbook')).Data = fh(app.store, app.x, app.params, app.runden);


        end

        % Event received function: AkkuGesundheit_HTML
        function AkkuGesundheit_HTMLEventReceived(app, event)

            switch event.HTMLEventName
                case 'akku_ordner_waehlen'
                    start = getpref('RP_data_tool','akku_ordner', pwd);   % zuletzt genutzter Ordner
                    ordner = uigetdir(start, 'Ordner mit .mf4-Logs waehlen');
                    figure(app.UIFigure);   % Fokus zurueck auf die App

                    if isequal(ordner, 0)   % Abbruch
                        sendEventToHTML(app.AkkuGesundheit_HTML, 'fortschritt', struct('abbruch', true));
                        return;
                    end
                    setpref('RP_data_tool','akku_ordner', ordner);

                    % Fortschritts-Callback fuer den Scanner
                    cb = @(p,t) fortschritt_senden(app, p, t);

                    try
                        gesundheit = akku_gesundheit_scan(ordner, app.params, cb);
                    catch err
                        sendEventToHTML(app.AkkuGesundheit_HTML, 'fortschritt', ...
                            struct('prozent',100,'text',['Fehler: ' err.message]));
                        uialert(app.UIFigure, err.message, 'Akku-Gesundheit');
                        return;
                    end

                    % Ergebnis merken (bleibt bei Tab-Refresh erhalten) und rendern
                    app.params.akku_gesundheit = gesundheit;
                    fh = app.payload_map('akku_gesundheit');
                    app.AkkuGesundheit_HTML.Data = fh(app.store, app.x, app.params, app.runden);
            end

        end

        % Event received function: Uebersicht_HTML
        function Uebersicht_HTMLEventReceived(app, event)

            d = event.HTMLEventData;
            switch event.HTMLEventName
                case 'runde_markiert'
                    t0 = x_zu_t(app.x, d.x_start);
                    t1 = x_zu_t(app.x, d.x_ende);
                    if isnan(t0) || isnan(t1), return; end
                    neu = struct('name', char(d.name), ...
                        't_start', min(t0,t1), 't_ende', max(t0,t1), 'fahrer', '');
                    if isempty(app.runden), app.runden = neu; else, app.runden(end+1) = neu; end

                case 'runde_loeschen'
                    if ~isempty(app.runden)
                        app.runden = app.runden(~strcmp({app.runden.name}, char(d.name)));
                    end

                otherwise
                    return;
            end

            % Runden betreffen mehrere Tabs -> alle neu versorgen
            app.alle_tabs_aktualisieren();

            function t = x_zu_t(x, xw)
                t = NaN;
                if isempty(x) || ~isfield(x,'werte') || isempty(x.werte), return; end
                [xu, iu] = unique(x.werte(:));            % interp1 braucht eindeutige Stuetzstellen
                tref = x.t_ref(:);
                t = interp1(xu, tref(iu), xw, 'linear', NaN);
            end


        end

        % Event received function: Potis_HTML
        function Potis_HTMLEventReceived(app, event)

            switch event.HTMLEventName
                case 'poti_kalibrierung_geaendert'
                    d = event.HTMLEventData;   % {ecke, v1, mm1, v2, mm2}

                    if ~isfield(app.params,'poti') || ~isstruct(app.params.poti)
                        app.params.poti = struct();
                    end
                    if ~isfield(app.params.poti,'kalibrierung') || ~isstruct(app.params.poti.kalibrierung)
                        app.params.poti.kalibrierung = struct();
                    end
                    app.params.poti.kalibrierung.(char(d.ecke)) = struct( ...
                        'v1', d.v1, 'mm1', d.mm1, 'v2', d.v2, 'mm2', d.mm2);

                otherwise
                    warning('DatenanalyseApp:Event', 'Unbehandeltes Event "%s".', event.HTMLEventName);
                    return;
            end

            % Nur den Potis-Tab neu versorgen
            idx = find(strcmp(app.tab_namen, 'potis'), 1);
            fh  = app.payload_map('potis');
            app.HTML_Handles(idx).Data = fh(app.store, app.x, app.params, app.runden);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout2 = uigridlayout(app.UIFigure);
            app.Panel = uipanel(app.GridLayout2);
            app.DynamicseVLabel = uilabel(app.Panel);
            app.DynamicseVLabel.Text = 'Dynamics e.V.';
            app.ReifentestViewerCarNo62Label = uilabel(app.Panel);
            app.ReifentestViewerCarNo62Label.Text = 'Datenanalyse  | Car No. 62     ';
            app.AuswahlPanel = uipanel(app.GridLayout2);
            app.AuswahlPanel.Title = 'Auswahl';
            app.GridLayout = uigridlayout(app.AuswahlPanel);
            app.OrdnerwhlenButton = uibutton(app.GridLayout, 'push');
            app.OrdnerwhlenButton.ButtonPushedFcn = createCallbackFcn(app, @OrdnerwhlenButtonPushed, true);
            app.OrdnerwhlenButton.Text = 'Ordner wählen';
            app.LadenButton = uibutton(app.GridLayout, 'push');
            app.LadenButton.ButtonPushedFcn = createCallbackFcn(app, @LadenButtonPushed, true);
            app.LadenButton.Text = 'Laden';
            app.xAchseDropDownLabel = uilabel(app.GridLayout);
            app.xAchseDropDownLabel.Text = 'x-Achse';
            app.xAchseDropDown = uidropdown(app.GridLayout);
            app.xAchseDropDown.Items = {'Distanz', 'Zeit'};
            app.xAchseDropDown.Value = 'Distanz';
            app.DropDown_3 = uidropdown(app.GridLayout);
            app.DropDown_3.Items = {'Ganzer Run'};
            app.DropDown_3.Value = 'Ganzer Run';
            app.dd_datei = uidropdown(app.GridLayout);
            app.dd_datei.Items = {'— kein Ordner gewählt —'};
            app.dd_datei.Value = '— kein Ordner gewählt —';
            app.TabGroup2 = uitabgroup(app.GridLayout2);
            app.bersichtTab = uitab(app.TabGroup2);
            app.bersichtTab.Title = 'Übersicht';
            app.Uebersicht_HTML = uihtml(app.bersichtTab);
            app.Uebersicht_HTML.HTMLSource = 'HTMLS\uebersicht.html';
            app.Uebersicht_HTML.HTMLEventReceivedFcn = createCallbackFcn(app, @Uebersicht_HTMLEventReceived, true);
            app.FahrdynamikTab = uitab(app.TabGroup2);
            app.FahrdynamikTab.Title = 'Fahrdynamik';
            app.Fahrdynamik_HTML = uihtml(app.FahrdynamikTab);
            app.Fahrdynamik_HTML.HTMLSource = 'HTMLS\fahrdynamik.html';
            app.FahrerTab = uitab(app.TabGroup2);
            app.FahrerTab.Title = 'Fahrer';
            app.Fahrer_HTML = uihtml(app.FahrerTab);
            app.Fahrer_HTML.HTMLSource = 'HTMLS\fahrer.html';
            app.FahrerprofileTab = uitab(app.TabGroup2);
            app.FahrerprofileTab.Title = 'Fahrerprofile';
            app.Fahrerprofile_HTML = uihtml(app.FahrerprofileTab);
            app.Fahrerprofile_HTML.HTMLSource = 'HTMLS\fahrerprofile.html';
            app.Fahrerprofile_HTML.HTMLEventReceivedFcn = createCallbackFcn(app, @Fahrerprofile_HTMLEventReceived, true);
            app.HardwareSoftwareTab = uitab(app.TabGroup2);
            app.HardwareSoftwareTab.Title = 'Hardware/Software';
            app.Hardware_Software_HTML = uihtml(app.HardwareSoftwareTab);
            app.Hardware_Software_HTML.HTMLSource = 'HTMLS\hardware_software.html';
            app.Hardware_Software_HTML.HTMLEventReceivedFcn = createCallbackFcn(app, @Hardware_Software_HTMLEventReceived, true);
            app.AkkuTab = uitab(app.TabGroup2);
            app.AkkuTab.Title = 'Akku';
            app.Akku_HTML = uihtml(app.AkkuTab);
            app.Akku_HTML.HTMLSource = 'HTMLS\akku.html';
            app.MechanikTab = uitab(app.TabGroup2);
            app.MechanikTab.Title = 'Mechanik';
            app.Mechanik_HTML = uihtml(app.MechanikTab);
            app.Mechanik_HTML.HTMLSource = 'HTMLS\mechanik.html';
            app.FahrzeugParameterTab = uitab(app.TabGroup2);
            app.FahrzeugParameterTab.Title = 'Fahrzeug-Parameter';
            app.FahrzeugParameter_HTML = uihtml(app.FahrzeugParameterTab);
            app.FahrzeugParameter_HTML.HTMLSource = 'HTMLS\parameter.html';
            app.ScratchbookTab = uitab(app.TabGroup2);
            app.ScratchbookTab.Title = 'Scratchbook';
            app.Scratchbook_HTML = uihtml(app.ScratchbookTab);
            app.Scratchbook_HTML.HTMLSource = 'HTMLS\scratchbook.html';
            app.Scratchbook_HTML.HTMLEventReceivedFcn = createCallbackFcn(app, @Scratchbook_HTMLEventReceived, true);
            app.AkkuGesundheitTab = uitab(app.TabGroup2);
            app.AkkuGesundheitTab.Title = 'Akku Gesundheit';
            app.AkkuGesundheit_HTML = uihtml(app.AkkuGesundheitTab);
            app.AkkuGesundheit_HTML.HTMLSource = 'HTMLS\akku_gesundheit.html';
            app.AkkuGesundheit_HTML.HTMLEventReceivedFcn = createCallbackFcn(app, @AkkuGesundheit_HTMLEventReceived, true);
            app.thermikTab = uitab(app.TabGroup2);
            app.thermikTab.Title = 'thermik';
            app.Thermik_HTML = uihtml(app.thermikTab);
            app.Thermik_HTML.HTMLSource = 'HTMLS\thermik.html';
            app.Potis = uitab(app.TabGroup2);
            app.Potis.Title = 'Potis';
            app.Potis_HTML = uihtml(app.Potis);
            app.Potis_HTML.HTMLSource = 'HTMLS\Potis.HTML';
            app.Potis_HTML.HTMLEventReceivedFcn = createCallbackFcn(app, @Potis_HTMLEventReceived, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RP_data_tool_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end